classdef GeometryGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeometryUIFigure          matlab.ui.Figure
        ResetButton               matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        ShowTransducerCheckBox    matlab.ui.control.CheckBox
        GeometryLabel             matlab.ui.control.Label
        HelpTransmitButton        matlab.ui.control.Button
        RotateXButton             matlab.ui.control.Button
        RotateYButton             matlab.ui.control.Button
        RotateZButton             matlab.ui.control.Button
        VisualizationSliderLabel  matlab.ui.control.Label
        VisualizationSlider       matlab.ui.control.Slider
        SpeedLabel                matlab.ui.control.Label
        QualityLabel              matlab.ui.control.Label
        GeometryDepthFieldLabel   matlab.ui.control.Label
        GeometryDepthField        matlab.ui.control.NumericEditField
        GeometryAxes              matlab.ui.control.UIAxes
        ShowBeamCheckBox          matlab.ui.control.CheckBox
        ShowDomainCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp          % Reference to the main app
        Geometry            % Struct that holds the geometry properties
        
        VesselVertices      % Matrix with the vessel vertices from the STL file
        ProcessedVertices   % Repositioned and resampled VesselVertices
        
    end
    
    methods (Access = private)
        
        function setValues(app)
            app.GeometryDepthField.Value = app.Geometry.startDepth*1e3; % [mm]
            
            app.ShowTransducerCheckBox.Value = ...
                app.Geometry.Visualization.ShowTransducer;
            
            app.ShowBeamCheckBox.Value = ...
                app.Geometry.Visualization.ShowBeam;
            
            app.ShowDomainCheckBox.Value = ...
                app.Geometry.Visualization.ShowDomain;
            
            app.VisualizationSlider.Value = ...
                log10(app.Geometry.Visualization.Fraction);
            
        end
        
       
        function updatePlot(app)
            
            custom_blue = [0.87,0.93,0.97];

            % Show the vertices of the vessel
            X = app.ProcessedVertices(1,:);
            Y = app.ProcessedVertices(2,:);
            Z = app.ProcessedVertices(3,:);
            
            plot3(app.GeometryAxes,X,Y,Z,'r.')
            
            % Get transducer and projection vertices
            S = app.Geometry.Domain.TransducerSurface;
            P = app.Geometry.Domain.TransducerProjection;
                
            V = [S; P]*1e3;     % Vertices in mm
            
            
            % Show the transducer
            if app.Geometry.Visualization.ShowTransducer
                F = [1 2 3 4];
                patch(app.GeometryAxes,'Vertices',V,'Faces',F,'FaceColor',custom_blue)
            end
            
            % Show the beam
            if app.Geometry.Visualization.ShowBeam
                F = [1 2 6 5; 
                     2 3 7 6; 
                     3 4 8 7; 
                     4 1 5 8];
                patch(app.GeometryAxes,'Vertices',V,'Faces',F,'FaceColor',custom_blue,'FaceAlpha',0.2)
            end
            
            % Show the simulation domain
            V = app.Geometry.Domain.Vertices*1e3;   % [mm]
            
            if app.Geometry.Visualization.ShowDomain
                F = [1 2 6 5; 
                     2 3 7 6; 
                     3 4 8 7; 
                     4 1 5 8];
                patch(app.GeometryAxes,'Vertices',V,'Faces',F,'FaceAlpha',0)
            end
                              
            disableDefaultInteractivity(app.GeometryAxes)
            set(app.GeometryAxes,'DataAspectRatio',[1 1 1])
            
            xlabel(app.GeometryAxes,'x (mm)')
            ylabel(app.GeometryAxes,'y (mm)')
            zlabel(app.GeometryAxes,'z (mm)')


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the geometry properties
            app.Geometry = app.CallingApp.Geometry;
            
            % Update the properties of the simulation domain:
            app.Geometry = compute_simulation_domain(app.Geometry, ...
                app.CallingApp.Medium, ...
                app.CallingApp.Transducer, ...
                app.CallingApp.Transmit);
            
            % Load the vertices of the STL file (units um):
            Vertices = load(app.Geometry.Visualization.VesselVerticesFile);
            
            % Put the vertices in a matrix:
            app.VesselVertices = [Vertices.X; Vertices.Y; Vertices.Z];
            
            % Process the vertices for visualization:
            app.ProcessedVertices = process_vertices(app.Geometry, app.VesselVertices);

            % Set the component values to the geometry properties
            setValues(app)
            updatePlot(app)
            
        end

        % Close request function: GeometryUIFigure
        function GeometryUIFigureCloseRequest(app, event)
            % Enable the Explore Geometry button in main app
            app.CallingApp.ExploreGeometryButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Geometry = reset_geometry();

            % Update the properties of the simulation domain:
            app.Geometry = compute_simulation_domain(app.Geometry, ...
                app.CallingApp.Medium, ...
                app.CallingApp.Transducer, ...
                app.CallingApp.Transmit);
            
            % Load the vertices of the STL file (units um):
            Vertices = load(app.Geometry.Visualization.VesselVerticesFile);
            
            % Put the vertices in a matrix:
            app.VesselVertices = [Vertices.X; Vertices.Y; Vertices.Z];
                        
            % Process the vertices for visualization:
            app.ProcessedVertices = process_vertices(app.Geometry, app.VesselVertices);
            
            setValues(app)   
            updatePlot(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
           
            % Enable the Explore Geometry button in main app
            app.CallingApp.ExploreGeometryButton.Enable = 'on';
            
            % Assign the new geometry properties to the geometry
            % properties of the calling app
            app.CallingApp.Geometry = app.Geometry;
                                  
            % Update the fields in the calling app
            setValuesMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Explore Geometry button in main app
            app.CallingApp.ExploreGeometryButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)      
        end

        % Value changed function: GeometryDepthField
        function GeometryDepthFieldValueChanged(app, event)
            app.Geometry.startDepth = app.GeometryDepthField.Value/1e3;  % [m]
            
            % Update the properties of the simulation domain:
            app.Geometry = compute_simulation_domain(app.Geometry, ...
                app.CallingApp.Medium, ...
                app.CallingApp.Transducer, ...
                app.CallingApp.Transmit);
            
            % Update the vessel tree vertices:
            app.ProcessedVertices = process_vertices(app.Geometry, app.VesselVertices);
            
            updatePlot(app)
        end

        % Button pushed function: RotateXButton
        function RotateXButtonPushed(app, event)
            
            % Rotation matrix for 90 degree rotation around x axis:
            R = [1 0 0 ; 0 0 -1 ; 0 1 0];
              
            app.Geometry.Rotation = R*app.Geometry.Rotation;
            
            % Update the properties of the simulation domain:
            app.Geometry = compute_simulation_domain(app.Geometry, ...
                app.CallingApp.Medium, ...
                app.CallingApp.Transducer, ...
                app.CallingApp.Transmit);
            
            % Update the vessel tree vertices:
            app.ProcessedVertices = process_vertices(app.Geometry, app.VesselVertices);
            
            updatePlot(app)
        end

        % Button pushed function: RotateYButton
        function RotateYButtonPushed(app, event)

            % Rotation matrix for 90 degree rotation around y axis:
            R = [0 0 1; 0 1 0; -1 0 0];
             
            app.Geometry.Rotation = R*app.Geometry.Rotation;
            
            % Update the properties of the simulation domain:
            app.Geometry = compute_simulation_domain(app.Geometry, ...
                app.CallingApp.Medium, ...
                app.CallingApp.Transducer, ...
                app.CallingApp.Transmit);
            
            % Update the vessel tree vertices:
            app.ProcessedVertices = process_vertices(app.Geometry, app.VesselVertices);
            
            updatePlot(app)
        end

        % Button pushed function: RotateZButton
        function RotateZButtonPushed(app, event)
            
            % Rotation matrix for 90 degree rotation around z axis:
            R = [0 -1 0; 1 0 0 ; 0 0 1];
            
            app.Geometry.Rotation = R*app.Geometry.Rotation;
            
            % Update the properties of the simulation domain:
            app.Geometry = compute_simulation_domain(app.Geometry, ...
                app.CallingApp.Medium, ...
                app.CallingApp.Transducer, ...
                app.CallingApp.Transmit);
            
            % Update the vessel tree vertices:
            app.ProcessedVertices = process_vertices(app.Geometry, app.VesselVertices);
            
            updatePlot(app)
        end

        % Value changed function: ShowTransducerCheckBox
        function ShowTransducerCheckBoxValueChanged(app, event)
            app.Geometry.Visualization.ShowTransducer = ...
                app.ShowTransducerCheckBox.Value;
            
            updatePlot(app)
        end

        % Value changed function: VisualizationSlider
        function VisualizationSliderValueChanged(app, event)
            app.Geometry.Visualization.Fraction = 10^(app.VisualizationSlider.Value);
            app.ProcessedVertices = process_vertices(app.Geometry, app.VesselVertices);
            updatePlot(app)
        end

        % Value changed function: ShowBeamCheckBox
        function ShowBeamCheckBoxValueChanged(app, event)
            app.Geometry.Visualization.ShowBeam = app.ShowBeamCheckBox.Value;
            updatePlot(app)            
        end

        % Value changed function: ShowDomainCheckBox
        function ShowDomainCheckBoxValueChanged(app, event)
            app.Geometry.Visualization.ShowDomain = app.ShowDomainCheckBox.Value;
            updatePlot(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeometryUIFigure = uifigure('Visible', 'off');
            app.GeometryUIFigure.Name = 'Geometry';
            app.GeometryUIFigure.CloseRequestFcn = createCallbackFcn(app, @GeometryUIFigureCloseRequest, true);
            app.ResetButton = uibutton(app.GeometryUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplyButton = uibutton(app.GeometryUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GeometryUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ShowTransducerCheckBox = uicheckbox(app.GeometryUIFigure);
            app.ShowTransducerCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTransducerCheckBoxValueChanged, true);
            app.ShowTransducerCheckBox.Text = 'Show transducer';
            app.GeometryLabel = uilabel(app.GeometryUIFigure);
            app.GeometryLabel.Text = 'Geometry';
            app.HelpTransmitButton = uibutton(app.GeometryUIFigure, 'push');
            app.HelpTransmitButton.Text = '?';
            app.RotateXButton = uibutton(app.GeometryUIFigure, 'push');
            app.RotateXButton.ButtonPushedFcn = createCallbackFcn(app, @RotateXButtonPushed, true);
            app.RotateXButton.Text = 'Rotate X';
            app.RotateYButton = uibutton(app.GeometryUIFigure, 'push');
            app.RotateYButton.ButtonPushedFcn = createCallbackFcn(app, @RotateYButtonPushed, true);
            app.RotateYButton.Text = 'Rotate Y';
            app.RotateZButton = uibutton(app.GeometryUIFigure, 'push');
            app.RotateZButton.ButtonPushedFcn = createCallbackFcn(app, @RotateZButtonPushed, true);
            app.RotateZButton.Text = 'Rotate Z';
            app.VisualizationSliderLabel = uilabel(app.GeometryUIFigure);
            app.VisualizationSliderLabel.Text = 'Visualization';
            app.VisualizationSlider = uislider(app.GeometryUIFigure);
            app.VisualizationSlider.Limits = [-4 0];
            app.VisualizationSlider.MajorTicks = [];
            app.VisualizationSlider.MajorTickLabels = {''};
            app.VisualizationSlider.ValueChangedFcn = createCallbackFcn(app, @VisualizationSliderValueChanged, true);
            app.VisualizationSlider.MinorTicks = [];
            app.VisualizationSlider.Value = -3;
            app.SpeedLabel = uilabel(app.GeometryUIFigure);
            app.SpeedLabel.Text = 'Speed';
            app.QualityLabel = uilabel(app.GeometryUIFigure);
            app.QualityLabel.Text = 'Quality';
            app.GeometryDepthFieldLabel = uilabel(app.GeometryUIFigure);
            app.GeometryDepthFieldLabel.Text = 'Geometry depth (mm)';
            app.GeometryDepthField = uieditfield(app.GeometryUIFigure, 'numeric');
            app.GeometryDepthField.ValueChangedFcn = createCallbackFcn(app, @GeometryDepthFieldValueChanged, true);
            app.GeometryAxes = uiaxes(app.GeometryUIFigure);
            title(app.GeometryAxes, '')
            xlabel(app.GeometryAxes, 'X')
            ylabel(app.GeometryAxes, 'Y')
            app.ShowBeamCheckBox = uicheckbox(app.GeometryUIFigure);
            app.ShowBeamCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowBeamCheckBoxValueChanged, true);
            app.ShowBeamCheckBox.Text = 'Show beam approximation';
            app.ShowDomainCheckBox = uicheckbox(app.GeometryUIFigure);
            app.ShowDomainCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDomainCheckBoxValueChanged, true);
            app.ShowDomainCheckBox.Text = 'Show simulation domain';
            % Show the figure after all components are created
            app.GeometryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeometryGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeometryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeometryUIFigure)
        end
    end
end