classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrobubbleFlowSimulatorUIFigure  matlab.ui.Figure
        MicrobubblesPanel               matlab.ui.container.Panel
        NumberOfMicrobubblesFieldLabel  matlab.ui.control.Label
        NumberOfMicrobubblesField       matlab.ui.control.EditField
        TypeFieldLabel                  matlab.ui.control.Label
        TypeField                       matlab.ui.control.EditField
        ModifyMicrobubblesButton        matlab.ui.control.Button
        MicrobubblesUIAxes              matlab.ui.control.UIAxes
        MicrobubblesLabel               matlab.ui.control.Label
        HelpMicrobubblesButton          matlab.ui.control.Button
        TransducerPanel                 matlab.ui.container.Panel
        TransducerField                 matlab.ui.control.EditField
        ModifyTransducerButton          matlab.ui.control.Button
        TransducerLabel                 matlab.ui.control.Label
        HelpTransducerButton            matlab.ui.control.Button
        AcquisitionPanel                matlab.ui.container.Panel
        PulsingSchemeDropDownLabel      matlab.ui.control.Label
        PulsingSchemeDropDown           matlab.ui.control.DropDown
        AcquisitionLabel                matlab.ui.control.Label
        HelpAcquisitionButton           matlab.ui.control.Button
        TimeBetweenPulsesFieldLabel     matlab.ui.control.Label
        TimeBetweenPulsesField          matlab.ui.control.NumericEditField
        NumberOfFramesFieldLabel        matlab.ui.control.Label
        NumberOfFramesField             matlab.ui.control.NumericEditField
        FrameRateFieldLabel             matlab.ui.control.Label
        FrameRateField                  matlab.ui.control.NumericEditField
        RunPanel                        matlab.ui.container.Panel
        LoadFileButton                  matlab.ui.control.Button
        WriteToFileButton               matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        ReconstructButton               matlab.ui.control.Button
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusMessageLabel              matlab.ui.control.Label
        HelpRunButton                   matlab.ui.control.Button
        GeometryPanel                   matlab.ui.container.Panel
        ExploreGeometryButton           matlab.ui.control.Button
        LoadGeometryButton              matlab.ui.control.Button
        GeometryLabel                   matlab.ui.control.Label
        HelpGeometryButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SimulationParametersPanel       matlab.ui.container.Panel
        ModifySimulationParametersButton  matlab.ui.control.Button
        SimulationparametersLabel       matlab.ui.control.Label
        HelpSimulationParametersButton  matlab.ui.control.Button
        SamplingRateFieldLabel          matlab.ui.control.Label
        SamplingRateField               matlab.ui.control.NumericEditField
        PointsPerWavelengthFieldLabel   matlab.ui.control.Label
        PointsPerWavelengthField        matlab.ui.control.NumericEditField
        MemoryRequirementFieldLabel     matlab.ui.control.Label
        MemoryRequirementField          matlab.ui.control.NumericEditField
        RuntimeFieldLabel               matlab.ui.control.Label
        RuntimeField                    matlab.ui.control.NumericEditField
        MediumPanel                     matlab.ui.container.Panel
        TissueField                     matlab.ui.control.EditField
        ModifyMediumButton              matlab.ui.control.Button
        MediumLabel                     matlab.ui.control.Label
        HelpMediumButton                matlab.ui.control.Button
        MediumInhomogeneityLabel        matlab.ui.control.Label
        MediumInhomogeneityField        matlab.ui.control.NumericEditField
        TransmitPulsePanel              matlab.ui.container.Panel
        TransmitPulseUIAxes             matlab.ui.control.UIAxes
        ModifyTransmitButton            matlab.ui.control.Button
        TransmitPulseLabel              matlab.ui.control.Label
        HelpTransmitPulseButton         matlab.ui.control.Button
        MechanicalindexFieldLabel       matlab.ui.control.Label
        MechanicalIndexField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MicrobubblesDialog 
        SimulationParametersDialog
        MediumDialog
        TransducerDialog
        TransmitDialog   
        GeometryDialog
    end
    
    properties (Access = public)
        Microbubble
        SimulationParameters
        Geometry
        Medium
        Transducer
        Acquisition
        Transmit
    end
    
   
    methods (Access = public)
        
        function setValuesMain(app)
            % Microbubbles
            app.TypeField.Value                 = app.Microbubble.Type;
            app.NumberOfMicrobubblesField.Value = num2str(app.Microbubble.Number);
            
            % Simulation parameters
            app.SamplingRateField.Value         = app.SimulationParameters.SamplingRate/1e6;    % (MHz)
            app.PointsPerWavelengthField.Value  = app.SimulationParameters.PointsPerWavelength;
            
            % Medium parameters
            app.TissueField.Value               = app.Medium.Tissue;
            app.MediumInhomogeneityField.Value  = app.Medium.Inhomogeneity*100; % Standard deviation as percentage
            
            % Transducer parameters
            app.TransducerField.Value           = app.Transducer.Type;
            
            % Acquisition
            app.FrameRateField.Value            = app.Acquisition.FrameRate;                    % (Hz)
            app.NumberOfFramesField.Value       = app.Acquisition.NumberOfFrames;
            app.PulsingSchemeDropDown.Value     = app.Acquisition.PulsingScheme;
            app.TimeBetweenPulsesField.Value    = app.Acquisition.TimeBetweenPulses*1e6;        % (us)
            
            % Transmit
            app.MechanicalIndexField.Value      = app.Transmit.MechanicalIndex;
            
            % Geometry
            app.Image.ImageSource               = app.Geometry.Visualization.Image;
      
        end
        
        function setComponentEnableMain(app)
            if strcmp(app.Acquisition.PulsingScheme, 'Standard')
                app.TimeBetweenPulsesField.Enable = 'off';
            else
                app.TimeBetweenPulsesField.Enable = 'on';
            end
        end
        
        function updateDistributionPlotMain(app)

            R = app.Microbubble.Distribution.Radii;
            P = app.Microbubble.Distribution.Probabilities;
            plot(app.MicrobubblesUIAxes,R*1e6,P,'r')
            
            app.MicrobubblesUIAxes.YTickLabel = [];
            
        end
        
        function updateTransmitPlotMain(app)
            
            p  = app.Transmit.PressureSignal;
            Fs = app.Transmit.SamplingRate;
            
            % Set up time array:
            N = length(p);
            t = (0:(N-1))/Fs;
                       
            % Plot pressure signal:
            plot(app.TransmitPulseUIAxes,t*1e6,p/1e3,'r')
            t_max = max(t)*1e6; % [us]
            xlim(app.TransmitPulseUIAxes,[0, t_max])

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('GUIfunctions')
            addpath('geometry_data')
            
            app.Microbubble             = reset_microbubble();
            app.Medium                  = reset_medium();
            app.Acquisition             = reset_acquisition();
            app.Transducer              = reset_transducer();

            app.Transmit                = reset_transmit(app.Transducer);
            app.SimulationParameters    = reset_simulation_parameters(...
                                            app.Transmit.CenterFrequency);
                                        
            app.Geometry.Type           = 'MouseBrain';
            app.Geometry                = reset_geometry(app.Geometry);
            
            
            setValuesMain(app)
            setComponentEnableMain(app)
            updateDistributionPlotMain(app)
            updateTransmitPlotMain(app)
        end

        % Button pushed function: ModifyMicrobubblesButton
        function ModifyMicrobubblesButtonPushed(app, event)
            app.ModifyMicrobubblesButton.Enable = 'off';
            app.MicrobubblesDialog = MicrobubblesGUI(app);
        end

        % Close request function: MicrobubbleFlowSimulatorUIFigure
        function MicrobubbleFlowSimulatorUIFigureCloseRequest(app, event)
            delete(app.MicrobubblesDialog) 
            delete(app.SimulationParametersDialog)
            delete(app.MediumDialog)
            delete(app.TransducerDialog)
            delete(app.TransmitDialog)
            delete(app.GeometryDialog)
            delete(app)

        end

        % Button pushed function: ModifyTransmitButton
        function ModifyTransmitButtonPushed(app, event)
            app.ModifyTransmitButton.Enable = 'off';
            app.TransmitDialog = TransmitGUI(app);
        end

        % Value changed function: PulsingSchemeDropDown
        function PulsingSchemeDropDownValueChanged(app, event)
            app.Acquisition.PulsingScheme = app.PulsingSchemeDropDown.Value;
            setComponentEnableMain(app)
            
        end

        % Button pushed function: ModifySimulationParametersButton
        function ModifySimulationParametersButtonPushed(app, event)
            app.ModifySimulationParametersButton.Enable = 'off';
            app.SimulationParametersDialog = SimulationParametersGUI(app);
            
        end

        % Button pushed function: ModifyMediumButton
        function ModifyMediumButtonPushed(app, event)
            app.ModifyMediumButton.Enable = 'off';
            app.MediumDialog = MediumGUI(app);
        end

        % Button pushed function: WriteToFileButton
        function WriteToFileButtonPushed(app, event)
            write_to_file(app.Microbubble,app.SimulationParameters,app.Geometry,...
                app.Transducer,app.Acquisition,app.Medium,app.Transmit)
            
        end

        % Button pushed function: ModifyTransducerButton
        function ModifyTransducerButtonPushed(app, event)
            app.ModifyTransducerButton.Enable = 'off';
            app.TransducerDialog = TransducerGUI(app);
        end

        % Button pushed function: ExploreGeometryButton
        function ExploreGeometryButtonPushed(app, event)
            app.ExploreGeometryButton.Enable = 'off';
            app.GeometryDialog = GeometryGUI(app);
        end

        % Value changed function: FrameRateField
        function FrameRateFieldValueChanged(app, event)
            app.Acquisition.FrameRate = app.FrameRateField.Value;     % (Hz)
        end

        % Value changed function: NumberOfFramesField
        function NumberOfFramesFieldValueChanged(app, event)
            app.Acquisition.NumberOfFrames = app.NumberOfFramesField.Value;
        end

        % Value changed function: TimeBetweenPulsesField
        function TimeBetweenPulsesFieldValueChanged(app, event)
            app.Acquisition.TimeBetweenPulses = app.TimeBetweenPulsesField.Value*1e-6; % (s)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrobubbleFlowSimulatorUIFigure = uifigure('Visible', 'off');
            app.MicrobubbleFlowSimulatorUIFigure.Name = 'Microbubble Flow Simulator';
            app.MicrobubbleFlowSimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrobubbleFlowSimulatorUIFigureCloseRequest, true);
            app.MicrobubblesPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.MicrobubblesPanel.Tooltip = {''};
            app.NumberOfMicrobubblesFieldLabel = uilabel(app.MicrobubblesPanel);
            app.NumberOfMicrobubblesFieldLabel.Text = {'Number of'; 'microbubbles'};
            app.NumberOfMicrobubblesField = uieditfield(app.MicrobubblesPanel, 'text');
            app.NumberOfMicrobubblesField.Editable = 'off';
            app.TypeFieldLabel = uilabel(app.MicrobubblesPanel);
            app.TypeFieldLabel.Text = 'Type';
            app.TypeField = uieditfield(app.MicrobubblesPanel, 'text');
            app.TypeField.Editable = 'off';
            app.ModifyMicrobubblesButton = uibutton(app.MicrobubblesPanel, 'push');
            app.ModifyMicrobubblesButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyMicrobubblesButtonPushed, true);
            app.ModifyMicrobubblesButton.Text = 'Modify';
            app.MicrobubblesUIAxes = uiaxes(app.MicrobubblesPanel);
            title(app.MicrobubblesUIAxes, '')
            xlabel(app.MicrobubblesUIAxes, 'Radius (μm)')
            ylabel(app.MicrobubblesUIAxes, 'Probability')
            app.MicrobubblesLabel = uilabel(app.MicrobubblesPanel);
            app.MicrobubblesLabel.Text = 'Microbubbles';
            app.HelpMicrobubblesButton = uibutton(app.MicrobubblesPanel, 'push');
            app.HelpMicrobubblesButton.Text = '?';
            app.TransducerPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.TransducerField = uieditfield(app.TransducerPanel, 'text');
            app.TransducerField.Editable = 'off';
            app.TransducerField.Value = 'P4-1';
            app.ModifyTransducerButton = uibutton(app.TransducerPanel, 'push');
            app.ModifyTransducerButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyTransducerButtonPushed, true);
            app.ModifyTransducerButton.Text = 'Modify';
            app.TransducerLabel = uilabel(app.TransducerPanel);
            app.TransducerLabel.Text = 'Transducer';
            app.HelpTransducerButton = uibutton(app.TransducerPanel, 'push');
            app.HelpTransducerButton.Text = '?';
            app.AcquisitionPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.PulsingSchemeDropDownLabel = uilabel(app.AcquisitionPanel);
            app.PulsingSchemeDropDownLabel.Text = 'Pulsing scheme';
            app.PulsingSchemeDropDown = uidropdown(app.AcquisitionPanel);
            app.PulsingSchemeDropDown.Items = {'Standard', 'Pulse inversion', 'Amplitude modulation', ''};
            app.PulsingSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulsingSchemeDropDownValueChanged, true);
            app.PulsingSchemeDropDown.Value = 'Standard';
            app.AcquisitionLabel = uilabel(app.AcquisitionPanel);
            app.AcquisitionLabel.Text = 'Acquisition';
            app.HelpAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.HelpAcquisitionButton.Text = '?';
            app.TimeBetweenPulsesFieldLabel = uilabel(app.AcquisitionPanel);
            app.TimeBetweenPulsesFieldLabel.Text = 'Time between pulses (μs)';
            app.TimeBetweenPulsesField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.TimeBetweenPulsesField.ValueChangedFcn = createCallbackFcn(app, @TimeBetweenPulsesFieldValueChanged, true);
            app.NumberOfFramesFieldLabel = uilabel(app.AcquisitionPanel);
            app.NumberOfFramesFieldLabel.Text = {'Number of frames'; ''};
            app.NumberOfFramesField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.NumberOfFramesField.ValueChangedFcn = createCallbackFcn(app, @NumberOfFramesFieldValueChanged, true);
            app.FrameRateFieldLabel = uilabel(app.AcquisitionPanel);
            app.FrameRateFieldLabel.Text = 'Frame rate (Hz)';
            app.FrameRateField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FrameRateField.ValueChangedFcn = createCallbackFcn(app, @FrameRateFieldValueChanged, true);
            app.RunPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.LoadFileButton = uibutton(app.RunPanel, 'push');
            app.LoadFileButton.Text = 'Load file';
            app.WriteToFileButton = uibutton(app.RunPanel, 'push');
            app.WriteToFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteToFileButtonPushed, true);
            app.WriteToFileButton.Text = 'Write to file';
            app.RUNButton = uibutton(app.RunPanel, 'push');
            app.RUNButton.Text = 'RUN';
            app.ReconstructButton = uibutton(app.RunPanel, 'push');
            app.ReconstructButton.Text = 'Reconstruct';
            app.StatusLampLabel = uilabel(app.RunPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.RunPanel);
            app.StatusMessageLabel = uilabel(app.RunPanel);
            app.StatusMessageLabel.Text = 'Status message';
            app.HelpRunButton = uibutton(app.RunPanel, 'push');
            app.HelpRunButton.Text = '?';
            app.GeometryPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.ExploreGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.ExploreGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreGeometryButtonPushed, true);
            app.ExploreGeometryButton.Text = {'Modify'; 'orientation'};
            app.LoadGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.LoadGeometryButton.Text = {'Load geometry'; 'and streamlines'};
            app.GeometryLabel = uilabel(app.GeometryPanel);
            app.GeometryLabel.Text = 'Geometry';
            app.HelpGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.HelpGeometryButton.Text = '?';
            app.Image = uiimage(app.GeometryPanel);
            app.Image.ImageSource = 'VasculatureDrawing.png';
            app.SimulationParametersPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.ModifySimulationParametersButton = uibutton(app.SimulationParametersPanel, 'push');
            app.ModifySimulationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ModifySimulationParametersButtonPushed, true);
            app.ModifySimulationParametersButton.Text = 'Modify';
            app.SimulationparametersLabel = uilabel(app.SimulationParametersPanel);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.HelpSimulationParametersButton = uibutton(app.SimulationParametersPanel, 'push');
            app.HelpSimulationParametersButton.Text = '?';
            app.SamplingRateFieldLabel = uilabel(app.SimulationParametersPanel);
            app.SamplingRateFieldLabel.Text = 'Sampling rate (MHz)';
            app.SamplingRateField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.SamplingRateField.Editable = 'off';
            app.PointsPerWavelengthFieldLabel = uilabel(app.SimulationParametersPanel);
            app.PointsPerWavelengthFieldLabel.Text = 'Points per wavelength';
            app.PointsPerWavelengthField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PointsPerWavelengthField.Editable = 'off';
            app.MemoryRequirementFieldLabel = uilabel(app.SimulationParametersPanel);
            app.MemoryRequirementFieldLabel.Text = {'Memory requirement'; 'estimate'};
            app.MemoryRequirementField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.MemoryRequirementField.Editable = 'off';
            app.RuntimeFieldLabel = uilabel(app.SimulationParametersPanel);
            app.RuntimeFieldLabel.Text = 'Runtime estimate';
            app.RuntimeField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.RuntimeField.Editable = 'off';
            app.MediumPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.TissueField = uieditfield(app.MediumPanel, 'text');
            app.ModifyMediumButton = uibutton(app.MediumPanel, 'push');
            app.ModifyMediumButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyMediumButtonPushed, true);
            app.ModifyMediumButton.Text = 'Modify';
            app.MediumLabel = uilabel(app.MediumPanel);
            app.MediumLabel.Text = 'Medium';
            app.HelpMediumButton = uibutton(app.MediumPanel, 'push');
            app.HelpMediumButton.Text = '?';
            app.MediumInhomogeneityLabel = uilabel(app.MediumPanel);
            app.MediumInhomogeneityLabel.Text = {'Medium '; 'inhomogeneity (%)'; ''};
            app.MediumInhomogeneityField = uieditfield(app.MediumPanel, 'numeric');
            app.TransmitPulsePanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.TransmitPulseUIAxes = uiaxes(app.TransmitPulsePanel);
            title(app.TransmitPulseUIAxes, '')
            xlabel(app.TransmitPulseUIAxes, 'time (μs)')
            ylabel(app.TransmitPulseUIAxes, 'Pressure (kPa)')
            app.ModifyTransmitButton = uibutton(app.TransmitPulsePanel, 'push');
            app.ModifyTransmitButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyTransmitButtonPushed, true);
            app.ModifyTransmitButton.Text = 'Modify';
            app.TransmitPulseLabel = uilabel(app.TransmitPulsePanel);
            app.TransmitPulseLabel.Text = 'Transmit';
            app.HelpTransmitPulseButton = uibutton(app.TransmitPulsePanel, 'push');
            app.HelpTransmitPulseButton.Text = '?';
            app.MechanicalindexFieldLabel = uilabel(app.TransmitPulsePanel);
            app.MechanicalindexFieldLabel.Text = 'Mechanical index';
            app.MechanicalIndexField = uieditfield(app.TransmitPulsePanel, 'numeric');
            app.MechanicalIndexField.ValueDisplayFormat = '%.2f';
            app.MechanicalIndexField.Editable = 'off';
            % Show the figure after all components are created
            app.MicrobubbleFlowSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrobubbleFlowSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrobubbleFlowSimulatorUIFigure)
        end
    end
end