classdef SimulationParametersGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationParametersUIFigure    matlab.ui.Figure
        SimulationParametersLabel       matlab.ui.control.Label
        HelpSimulationParametersButton  matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        PointsPerWavelengthFieldLabel   matlab.ui.control.Label
        PointsPerWavelengthField        matlab.ui.control.NumericEditField
        CFLFieldLabel                   matlab.ui.control.Label
        CFLField                        matlab.ui.control.NumericEditField
        SamplingRateFieldLabel          matlab.ui.control.Label
        SamplingRateField               matlab.ui.control.NumericEditField
        MemoryRequirementFieldLabel     matlab.ui.control.Label
        MemoryRequirementField          matlab.ui.control.EditField
        RuntimeFieldLabel               matlab.ui.control.Label
        RuntimeField                    matlab.ui.control.EditField
        TransmitfrequencyMHzLabel       matlab.ui.control.Label
        TransmitFrequencyField          matlab.ui.control.NumericEditField
        IndependentVariableButtonGroup  matlab.ui.container.ButtonGroup
        CFLButton                       matlab.ui.control.RadioButton
        SamplingRateButton              matlab.ui.control.RadioButton
        IndependentvariableLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp              % Reference to the main app
        SimulationParameters    % Struct that holds the simulation parameters
    end
    
    methods (Access = private)
        
        function setValues(app)
            % Set the component values to the transmit properties
            app.SamplingRateField.Value         = app.SimulationParameters.SamplingRate/1e6;        % Show in MHz
            app.PointsPerWavelengthField.Value  = app.SimulationParameters.PointsPerWavelength;
            app.CFLField.Value                  = app.SimulationParameters.CFL;
            app.TransmitFrequencyField.Value    = app.CallingApp.Transmit.CenterFrequency/1e6;      % Show in MHz

            switch app.SimulationParameters.IndependentVariable
                case 'CFL'
                    app.CFLButton.Value             = true;
                case 'Sampling rate'
                    app.SamplingRateButton.Value    = true;
            end
            
        end
        
        function setComponentEnable(app)
            
            switch app.SimulationParameters.IndependentVariable
                case 'CFL'
                    app.CFLField.Enable             = 'on';
                    app.SamplingRateField.Enable    = 'off';
                case 'Sampling rate'
                    app.CFLField.Enable             = 'off';
                    app.SamplingRateField.Enable    = 'on';
            end
            
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the simulation parameters
            app.SimulationParameters = app.CallingApp.SimulationParameters;

            % Set the component values to the simulation parameters
            setValues(app)
            
            % Control whether components are enabled or not
            setComponentEnable(app)
            
        end

        % Close request function: SimulationParametersUIFigure
        function SimulationParametersUIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.ModifySimulationParametersButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = reset_simulation_parameters(f0);
            setValues(app)
            setComponentEnable(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Enable the Modify Transmit button in main app
            app.CallingApp.ModifySimulationParametersButton.Enable = 'on';
            
            % Assign the new transmit properties to the transmit
            % properties of the calling app
            app.CallingApp.SimulationParameters = app.SimulationParameters;
            
            % Update the fields in the calling app
            setValuesMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.ModifySimulationParametersButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Value changed function: SamplingRateField
        function SamplingRateFieldValueChanged(app, event)
            app.SimulationParameters.SamplingRate = app.SamplingRateField.Value*1e6;    % Store in Hz    
            
            % Update CFL
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, f0);
            
            setValues(app)
            
        end

        % Value changed function: PointsPerWavelengthField
        function PointsPerWavelengthFieldValueChanged(app, event)
            app.SimulationParameters.PointsPerWavelength = app.PointsPerWavelengthField.Value;  
            
            % Update CFL or sampling rate
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, f0);
            
            setValues(app)
        end

        % Value changed function: CFLField
        function CFLFieldValueChanged(app, event)
            app.SimulationParameters.CFL = app.CFLField.Value;  
            
            % Update sampling rate
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, f0);
            
            setValues(app)
        end

        % Selection changed function: IndependentVariableButtonGroup
        function IndependentVariableButtonGroupSelectionChanged(app, event)
            selectedButton = app.IndependentVariableButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'CFL'
                    app.SimulationParameters.IndependentVariable = 'CFL';
                case 'Sampling rate'
                    app.SimulationParameters.IndependentVariable = 'Sampling rate';
            end
            
            setComponentEnable(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationParametersUIFigure = uifigure('Visible', 'off');
            app.SimulationParametersUIFigure.Name = 'Simulation parameters';
            app.SimulationParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulationParametersUIFigureCloseRequest, true);
            app.SimulationParametersLabel = uilabel(app.SimulationParametersUIFigure);
            app.SimulationParametersLabel.Text = 'Simulation parameters';
            app.HelpSimulationParametersButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.HelpSimulationParametersButton.Text = '?';
            app.ApplyButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResetButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PointsPerWavelengthFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.PointsPerWavelengthFieldLabel.Text = 'Points per wavelength';
            app.PointsPerWavelengthField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.PointsPerWavelengthField.ValueChangedFcn = createCallbackFcn(app, @PointsPerWavelengthFieldValueChanged, true);
            app.CFLFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.CFLFieldLabel.Text = 'CFL';
            app.CFLField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.CFLField.ValueChangedFcn = createCallbackFcn(app, @CFLFieldValueChanged, true);
            app.SamplingRateFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.SamplingRateFieldLabel.Text = 'Sampling rate (MHz)';
            app.SamplingRateField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.SamplingRateField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateFieldValueChanged, true);
            app.MemoryRequirementFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.MemoryRequirementFieldLabel.Text = {'Memory requirement'; 'estimate'};
            app.MemoryRequirementField = uieditfield(app.SimulationParametersUIFigure, 'text');
            app.MemoryRequirementField.Editable = 'off';
            app.RuntimeFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.RuntimeFieldLabel.Text = 'Runtime estimate';
            app.RuntimeField = uieditfield(app.SimulationParametersUIFigure, 'text');
            app.RuntimeField.Editable = 'off';
            app.TransmitfrequencyMHzLabel = uilabel(app.SimulationParametersUIFigure);
            app.TransmitfrequencyMHzLabel.Text = 'Transmit frequency (MHz)';
            app.TransmitFrequencyField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.TransmitFrequencyField.Editable = 'off';
            app.TransmitFrequencyField.Enable = 'off';
            app.IndependentVariableButtonGroup = uibuttongroup(app.SimulationParametersUIFigure);
            app.IndependentVariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IndependentVariableButtonGroupSelectionChanged, true);
            app.CFLButton = uiradiobutton(app.IndependentVariableButtonGroup);
            app.CFLButton.Text = 'CFL';
            app.CFLButton.Value = true;
            app.SamplingRateButton = uiradiobutton(app.IndependentVariableButtonGroup);
            app.SamplingRateButton.Text = 'Sampling rate';
            app.IndependentvariableLabel = uilabel(app.SimulationParametersUIFigure);
            app.IndependentvariableLabel.Text = 'Independent variable';
            % Show the figure after all components are created
            app.SimulationParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulationParametersGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationParametersUIFigure)
        end
    end
end