classdef TransmitGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransmitUIFigure            matlab.ui.Figure
        CenterFrequencyFieldLabel   matlab.ui.control.Label
        CenterFrequencyField        matlab.ui.control.NumericEditField
        NumberOfCyclesFieldLabel    matlab.ui.control.Label
        NumberOfCyclesField         matlab.ui.control.NumericEditField
        AcousticPressureFieldLabel  matlab.ui.control.Label
        AcousticPressureField       matlab.ui.control.NumericEditField
        LateralFocusFieldLabel      matlab.ui.control.Label
        LateralFocusField           matlab.ui.control.NumericEditField
        PulseTypeDropDownLabel      matlab.ui.control.Label
        PulseTypeDropDown           matlab.ui.control.DropDown
        LoadWaveformButton          matlab.ui.control.Button
        LoadWaveformLabel           matlab.ui.control.Label
        PressurePulseAxes           matlab.ui.control.UIAxes
        DrivingPulseAxes            matlab.ui.control.UIAxes
        MechanicalIndexFieldLabel   matlab.ui.control.Label
        MechanicalIndexField        matlab.ui.control.NumericEditField
        WaveformLabel               matlab.ui.control.Label
        BeamProfileLabel            matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        AdvancedCheckBox            matlab.ui.control.CheckBox
        LoadApodizationButton       matlab.ui.control.Button
        LoadDelaysButton            matlab.ui.control.Button
        TransmitLabel               matlab.ui.control.Label
        HelpTransmitButton          matlab.ui.control.Button
        EnvelopeTypeDropDownLabel   matlab.ui.control.Label
        EnvelopeTypeDropDown        matlab.ui.control.DropDown
        AngleFieldLabel             matlab.ui.control.Label
        AngleField                  matlab.ui.control.NumericEditField
        DelayTypeDropDown           matlab.ui.control.DropDown
        ApodizationTypeDropDown     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp  % Reference to the main app
        Transmit    % Struct that holds the transmit properties
    end
    
    methods (Access = private)
        
        function setValues(app)
            % Set the component values to the transmit properties
            app.PulseTypeDropDown.Value     = app.Transmit.Type;
            app.CenterFrequencyField.Value  = app.Transmit.CenterFrequency/1e6;     % Set in MHz
            app.NumberOfCyclesField.Value   = app.Transmit.NumberOfCycles;
            app.AcousticPressureField.Value = app.Transmit.AcousticPressure/1e3;    % Set in kPa
            app.EnvelopeTypeDropDown.Value  = app.Transmit.Envelope;
            
            app.AdvancedCheckBox.Value          = app.Transmit.Advanced;
            app.LateralFocusField.Value         = app.Transmit.LateralFocus*1e3;    % Set in mm 
            app.AngleField.Value                = app.Transmit.Angle;
            app.DelayTypeDropDown.Value         = app.Transmit.DelayType;
            app.ApodizationTypeDropDown.Value   = app.Transmit.ApodizationType;
            app.MechanicalIndexField.Value      = app.Transmit.MechanicalIndex;
            
        end
        
        function setComponentEnable(app)
            % Control whether components are enabled or not
            
            % Envelope type sinusoidal waveform
            if strcmp(app.Transmit.Type,'Cosine envelope')
                app.EnvelopeTypeDropDown.Enable         = 'on';
                app.EnvelopeTypeDropDownLabel.Enable    = 'on';
            else
                app.EnvelopeTypeDropDown.Enable         = 'off';
                app.EnvelopeTypeDropDownLabel.Enable    = 'off';
            end
            
            % Custom waveform (load from file)
            if strcmp(app.Transmit.Type,'Custom (voltage)') || strcmp(app.Transmit.Type,'Custom (pressure)')
                app.CenterFrequencyField.Enable         = 'off';
                app.CenterFrequencyFieldLabel.Enable    = 'off';
                app.NumberOfCyclesField.Enable          = 'off';
                app.NumberOfCyclesFieldLabel.Enable     = 'off';
                app.AcousticPressureField.Enable        = 'off'; 
                app.AcousticPressureFieldLabel.Enable   = 'off';
                
                app.LoadWaveformButton.Enable           = 'on';
                app.LoadWaveformLabel.Enable            = 'on';
            else
                app.CenterFrequencyField.Enable         = 'on';
                app.CenterFrequencyFieldLabel.Enable    = 'on';
                app.NumberOfCyclesField.Enable          = 'on';
                app.NumberOfCyclesFieldLabel.Enable     = 'on';
                app.AcousticPressureField.Enable        = 'on'; 
                app.AcousticPressureFieldLabel.Enable   = 'on';
                
                app.LoadWaveformButton.Enable           = 'off';
                app.LoadWaveformLabel.Enable            = 'off';
            end
            
            % Advanced components
            if app.Transmit.Advanced
                app.ApodizationTypeDropDown.Enable  = 'on';
                app.DelayTypeDropDown.Enable        = 'on';
                
            else
                app.ApodizationTypeDropDown.Enable  = 'off';
                app.DelayTypeDropDown.Enable        = 'off';
            end
            
            % Advanced load buttons
            if app.Transmit.Advanced && ...
                    strcmp(app.Transmit.DelayType,'Load delays')
                app.LoadDelaysButton.Enable         = 'on';
            else
                app.LoadDelaysButton.Enable         = 'off';
            end
            
            if app.Transmit.Advanced && ...
                    strcmp(app.Transmit.ApodizationType,'Load apodization')
                app.LoadApodizationButton.Enable  = 'on';
            else
                app.LoadApodizationButton.Enable  = 'off';
            end
            
            % Enable focus and angle to compute delays
            if strcmp(app.Transmit.DelayType,'Compute delays')
                app.AngleField.Enable               = 'on';
                app.AngleFieldLabel.Enable          = 'on';
                app.LateralFocusField.Enable        = 'on';
                app.LateralFocusFieldLabel.Enable   = 'on';
            else
                app.AngleField.Enable               = 'off';
                app.AngleFieldLabel.Enable          = 'off';
                app.LateralFocusField.Enable        = 'off';
                app.LateralFocusFieldLabel.Enable   = 'off';
                
            end
            
        end
        
        function updatePlots(app)

            V  = app.Transmit.VoltageSignal;
            p  = app.Transmit.PressureSignal;
            Fs = app.Transmit.SamplingRate;
            
            % Set up time arrays:
            N1 = length(V);
            N2 = length(p);
            t1 = (0:(N1-1))/Fs;
            t2 = (0:(N2-1))/Fs;
             
            % Plot voltage signal:
            if ~isempty(t1)
                % Both voltage and pressure signals defined
                
                % Get maximum time in t1 and t2 for axis limits:
                t_max = max(max(t1),max(t2))*1e6;   % [us]
                
                plot(app.DrivingPulseAxes,t1*1e6,V,'r')
                app.DrivingPulseAxes.YTickLabel = [];
                
            else
                % No voltage signal defined
                
                % Get maximum time in t1 and t2 for axis limits:
                t_max = max(t2)*1e6;                % [us]
                
                % Remove voltage signal plot:
                delete(app.DrivingPulseAxes.Children)
            end
            
            % Plot pressure signal:
            plot(app.PressurePulseAxes,t2*1e6,p/1e3,'r')
            xlim(app.PressurePulseAxes,[0, t_max])
            xlim(app.DrivingPulseAxes, [0, t_max])

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the transmit properties
            app.Transmit = app.CallingApp.Transmit;

            % Set the component values to the transmit properties
            setValues(app)
            setComponentEnable(app)
            updatePlots(app)
            
        end

        % Close request function: TransmitUIFigure
        function TransmitUIFigureCloseRequest(app, event)
            % Enable the Modify Transmit button in main app
            app.CallingApp.ModifyTransmitButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Value changed function: PulseTypeDropDown
        function PulseTypeDropDownValueChanged(app, event)
            app.Transmit.Type = app.PulseTypeDropDown.Value;
            
            if ~strcmp(app.Transmit.Type,'Custom (voltage)') && ...
                    ~strcmp(app.Transmit.Type,'Custom (pressure)')
                
                app.Transmit = get_voltage_signal(app.Transmit);
                app.Transmit = get_pressure_signal(app.Transmit,app.CallingApp.Transducer);
            end
            setComponentEnable(app)
            updatePlots(app)
        end

        % Value changed function: CenterFrequencyField
        function CenterFrequencyFieldValueChanged(app, event)
            app.Transmit.CenterFrequency = app.CenterFrequencyField.Value*1e6; % Store in Hz 
            
            app.Transmit = get_voltage_signal(app.Transmit);
            app.Transmit = get_pressure_signal(app.Transmit,app.CallingApp.Transducer);
            
            % Update the mechanical index and the plots
            app.Transmit.MechanicalIndex = compute_mechanical_index(app.Transmit);
            setValues(app)
            updatePlots(app)
            
        end

        % Value changed function: NumberOfCyclesField
        function NumberOfCyclesFieldValueChanged(app, event)
            app.Transmit.NumberOfCycles = app.NumberOfCyclesField.Value;
            app.Transmit = get_voltage_signal(app.Transmit);
            app.Transmit = get_pressure_signal(app.Transmit,app.CallingApp.Transducer);
            updatePlots(app)
        end

        % Value changed function: AcousticPressureField
        function AcousticPressureFieldValueChanged(app, event)
            app.Transmit.AcousticPressure = app.AcousticPressureField.Value*1e3; % Store in kPa
            app.Transmit = get_voltage_signal(app.Transmit);
            app.Transmit = get_pressure_signal(app.Transmit,app.CallingApp.Transducer);
            
            % Update the mechanical index and the plots
            app.Transmit.MechanicalIndex = compute_mechanical_index(app.Transmit);
            setValues(app)
            updatePlots(app)
        end

        % Value changed function: EnvelopeTypeDropDown
        function EnvelopeTypeDropDownValueChanged(app, event)
            app.Transmit.Envelope = app.EnvelopeTypeDropDown.Value;
            app.Transmit = get_voltage_signal(app.Transmit);
            app.Transmit = get_pressure_signal(app.Transmit,app.CallingApp.Transducer);
            updatePlots(app)
        end

        % Value changed function: AdvancedCheckBox
        function AdvancedCheckBoxValueChanged(app, event)
            app.Transmit.Advanced = app.AdvancedCheckBox.Value;
            setComponentEnable(app)
        end

        % Value changed function: LateralFocusField
        function LateralFocusFieldValueChanged(app, event)
            app.Transmit.LateralFocus = app.LateralFocusField.Value/1e3; % Store in m
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Transmit = reset_transmit(app.CallingApp.Transducer);
            setValues(app)   
            setComponentEnable(app)
            updatePlots(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Enable the Modify Transmit button in main app
            app.CallingApp.ModifyTransmitButton.Enable = 'on';
            
            % Assign the new transmit properties to the transmit
            % properties of the calling app
            app.CallingApp.Transmit = app.Transmit;
            
            % Update the simulation parameters (changing the transmit
            % center frequency changes the simulation parameters)
            app.CallingApp.SimulationParameters = update_simulation_parameters(...
                app.CallingApp.SimulationParameters, app.Transmit.CenterFrequency);
                        
            % Update the fields in the calling app
            setValuesMain(app.CallingApp)
            updateTransmitPlotMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Modify Transmit button in main app
            app.CallingApp.ModifyTransmitButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)      
        end

        % Value changed function: AngleField
        function AngleFieldValueChanged(app, event)
            app.Transmit.Angle = app.AngleField.Value;            
        end

        % Value changed function: DelayTypeDropDown
        function DelayTypeDropDownValueChanged(app, event)
            app.Transmit.DelayType = app.DelayTypeDropDown.Value;
            setComponentEnable(app)
            
            % When switching back to computing delays from focus and angle,
            % remove loaded delays, if any.
            if strcmp(app.Transmit.DelayType,'Compute delays')...
                    && isfield(app.Transmit,'Delays')
                app.Transmit = rmfield(app.Transmit,'Delays');
                
            end
        end

        % Value changed function: ApodizationTypeDropDown
        function ApodizationTypeDropDownValueChanged(app, event)
            app.Transmit.ApodizationType = app.ApodizationTypeDropDown.Value;
            setComponentEnable(app)
            
            % When switching back to uniform apodization,
            % remove loaded apodization, if any.
            if strcmp(app.Transmit.ApodizationType,'Uniform apodization')...
                    && isfield(app.Transmit,'Apodization')
                app.Transmit = rmfield(app.Transmit,'Apodization');
                
            end
        end

        % Button pushed function: LoadDelaysButton
        function LoadDelaysButtonPushed(app, event)
            [file,folder] = uigetfile; 
            Delays = load([folder file]);
            app.Transmit.Delays = Delays.delays;
            
            % Show app windows again after closing uigetfile window
            figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
            figure(app.TransmitUIFigure)
        end

        % Button pushed function: LoadApodizationButton
        function LoadApodizationButtonPushed(app, event)
            [file,folder] = uigetfile; 
            Apodization = load([folder file]);
            app.Transmit.Apodization = Apodization.apodization;
            
            % Show app windows again after closing uigetfile window
            figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
            figure(app.TransmitUIFigure)
        end

        % Button pushed function: LoadWaveformButton
        function LoadWaveformButtonPushed(app, event)
            [file,folder] = uigetfile; 
            CustomSignal = load([folder file]);
            
            switch app.Transmit.Type
                case 'Custom (voltage)'
                    app.Transmit.VoltageSignal = CustomSignal.V;
                    app.Transmit = get_pressure_signal(...
                        app.Transmit,app.CallingApp.Transducer);
                case 'Custom (pressure)'
                    app.Transmit.VoltageSignal = [];
                    app.Transmit.PressureSignal = CustomSignal.p;
            end
            
            updatePlots(app)
            
            % Show app windows again after closing uigetfile window
            figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
            figure(app.TransmitUIFigure)
            
            % Ask the user to enter the center frequency of the loaded
            % signal:
            prompt = {'Enter center frequency (MHz):'};
            dlgtitle = 'Input';     % Dialog box title
            dims = [1 35];          % Text field dimensions
            definput = {'2.5'};     % Default answer
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            user_val = str2double(answer{1});
            
            if isnan(user_val) || ~isreal(user_val)
                user_val = 2.5;
            end
            
            app.Transmit.CenterFrequency = user_val*1e6;    % [Hz]
            
            setValues(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransmitUIFigure = uifigure('Visible', 'off');
            app.TransmitUIFigure.Name = 'Transmit';
            app.TransmitUIFigure.CloseRequestFcn = createCallbackFcn(app, @TransmitUIFigureCloseRequest, true);
            app.CenterFrequencyFieldLabel = uilabel(app.TransmitUIFigure);
            app.CenterFrequencyFieldLabel.Text = 'Center frequency (MHz)';
            app.CenterFrequencyField = uieditfield(app.TransmitUIFigure, 'numeric');
            app.CenterFrequencyField.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyFieldValueChanged, true);
            app.NumberOfCyclesFieldLabel = uilabel(app.TransmitUIFigure);
            app.NumberOfCyclesFieldLabel.Text = 'Number of cycles';
            app.NumberOfCyclesField = uieditfield(app.TransmitUIFigure, 'numeric');
            app.NumberOfCyclesField.ValueChangedFcn = createCallbackFcn(app, @NumberOfCyclesFieldValueChanged, true);
            app.AcousticPressureFieldLabel = uilabel(app.TransmitUIFigure);
            app.AcousticPressureFieldLabel.Text = {'Peak negative acoustic'; 'pressure amplitude (kPa)'};
            app.AcousticPressureField = uieditfield(app.TransmitUIFigure, 'numeric');
            app.AcousticPressureField.ValueChangedFcn = createCallbackFcn(app, @AcousticPressureFieldValueChanged, true);
            app.LateralFocusFieldLabel = uilabel(app.TransmitUIFigure);
            app.LateralFocusFieldLabel.Text = 'Lateral focus (mm)';
            app.LateralFocusField = uieditfield(app.TransmitUIFigure, 'numeric');
            app.LateralFocusField.ValueChangedFcn = createCallbackFcn(app, @LateralFocusFieldValueChanged, true);
            app.PulseTypeDropDownLabel = uilabel(app.TransmitUIFigure);
            app.PulseTypeDropDownLabel.Text = 'Pulse type';
            app.PulseTypeDropDown = uidropdown(app.TransmitUIFigure);
            app.PulseTypeDropDown.Items = {'Three-level', 'Cosine envelope', 'Custom (voltage)', 'Custom (pressure)'};
            app.PulseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseTypeDropDownValueChanged, true);
            app.PulseTypeDropDown.Value = 'Three-level';
            app.LoadWaveformButton = uibutton(app.TransmitUIFigure, 'push');
            app.LoadWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveformButtonPushed, true);
            app.LoadWaveformButton.Text = 'Load';
            app.LoadWaveformLabel = uilabel(app.TransmitUIFigure);
            app.LoadWaveformLabel.Text = 'Load waveform';
            app.PressurePulseAxes = uiaxes(app.TransmitUIFigure);
            title(app.PressurePulseAxes, '')
            xlabel(app.PressurePulseAxes, 'Time (μs)')
            ylabel(app.PressurePulseAxes, 'Pressure (kPa)')
            app.DrivingPulseAxes = uiaxes(app.TransmitUIFigure);
            title(app.DrivingPulseAxes, '')
            xlabel(app.DrivingPulseAxes, 'Time (μs)')
            ylabel(app.DrivingPulseAxes, 'Voltage (a.u.)')
            app.DrivingPulseAxes.YTickLabel = '';
            app.MechanicalIndexFieldLabel = uilabel(app.TransmitUIFigure);
            app.MechanicalIndexFieldLabel.Text = 'Mechanical index';
            app.MechanicalIndexField = uieditfield(app.TransmitUIFigure, 'numeric');
            app.MechanicalIndexField.ValueDisplayFormat = '%.2f';
            app.MechanicalIndexField.Editable = 'off';
            app.WaveformLabel = uilabel(app.TransmitUIFigure);
            app.WaveformLabel.Text = 'Waveform';
            app.BeamProfileLabel = uilabel(app.TransmitUIFigure);
            app.BeamProfileLabel.Text = 'Beam profile';
            app.ResetButton = uibutton(app.TransmitUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplyButton = uibutton(app.TransmitUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.TransmitUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AdvancedCheckBox = uicheckbox(app.TransmitUIFigure);
            app.AdvancedCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedCheckBoxValueChanged, true);
            app.AdvancedCheckBox.Text = 'Advanced';
            app.LoadApodizationButton = uibutton(app.TransmitUIFigure, 'push');
            app.LoadApodizationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadApodizationButtonPushed, true);
            app.LoadApodizationButton.Text = 'Load';
            app.LoadDelaysButton = uibutton(app.TransmitUIFigure, 'push');
            app.LoadDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDelaysButtonPushed, true);
            app.LoadDelaysButton.Text = 'Load';
            app.TransmitLabel = uilabel(app.TransmitUIFigure);
            app.TransmitLabel.Text = 'Transmit';
            app.HelpTransmitButton = uibutton(app.TransmitUIFigure, 'push');
            app.HelpTransmitButton.Text = '?';
            app.EnvelopeTypeDropDownLabel = uilabel(app.TransmitUIFigure);
            app.EnvelopeTypeDropDownLabel.Text = 'Envelope type';
            app.EnvelopeTypeDropDown = uidropdown(app.TransmitUIFigure);
            app.EnvelopeTypeDropDown.Items = {'Rectangular', 'Hann', ''};
            app.EnvelopeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EnvelopeTypeDropDownValueChanged, true);
            app.EnvelopeTypeDropDown.Value = 'Rectangular';
            app.AngleFieldLabel = uilabel(app.TransmitUIFigure);
            app.AngleFieldLabel.Text = 'Angle (degrees)';
            app.AngleField = uieditfield(app.TransmitUIFigure, 'numeric');
            app.AngleField.Limits = [-90 90];
            app.AngleField.ValueChangedFcn = createCallbackFcn(app, @AngleFieldValueChanged, true);
            app.DelayTypeDropDown = uidropdown(app.TransmitUIFigure);
            app.DelayTypeDropDown.Items = {'Compute delays', 'Load delays'};
            app.DelayTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DelayTypeDropDownValueChanged, true);
            app.DelayTypeDropDown.Value = 'Compute delays';
            app.ApodizationTypeDropDown = uidropdown(app.TransmitUIFigure);
            app.ApodizationTypeDropDown.Items = {'Uniform apodization', 'Load apodization'};
            app.ApodizationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ApodizationTypeDropDownValueChanged, true);
            app.ApodizationTypeDropDown.Value = 'Uniform apodization';
            % Show the figure after all components are created
            app.TransmitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransmitGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransmitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransmitUIFigure)
        end
    end
end